classdef RMP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        LeftButton      matlab.ui.control.Button
        RightButton     matlab.ui.control.Button
        EditFieldLabel  matlab.ui.control.Label
        EditField       matlab.ui.control.EditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tree = load_mvnx(uigetfile);
            mvnxVersion = tree;
            frameRate = tree.subject.frameRate;                                          % Pulls the framerate and creates variable
            suitLabel = tree.subject.label;                                              % Pulls the label and creates variable
            originalFilename = tree.subject.originalFilename;                            % Pulls the original file name and creates variable
            recDate = tree.subject.recDate;                                              % Pulls the record date and creates variable
            segmentCount = tree.subject.segmentCount;                                    % Pulls the segment count and creates variable

            time = [tree.subject.frames.frame.time];
            time = time(4:end-1)';
            time = time/1000;


            index = [tree.subject.frames.frame(4:end).index];
            index(end) = []; 
            
            %retrieve sensor labels
%creates a struct with sensor data
if isfield(tree.subject,'sensors') && isstruct(tree.subject.sensors)
    sensorData = tree.subject.sensors.sensor;
end
%retrieve segment labels
%creates a struct with segment definitions
if isfield(tree.subject,'segments') && isstruct(tree.subject.segments)
    segmentData = tree.subject.segments.segment;
end
%retrieve the data frames from the subject
nSamples = length(tree.subject.frames.frame);
%pre allocate
%pre allocate some memory for the position of Segment1
p_Segment1 = zeros(nSamples,3);
%read the data from the structure e.g. segment 1
if isfield(tree.subject.frames.frame(1),'position')
    for i=[1:nSamples]
        p_Segment1(i,:)=tree.subject.frames.frame(i).position(1:3);
    end
   end


            run('SegmentPosition.m');
            run('JointAngle.m');
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            run('Left_extraction.m')
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            run('Right_extraction.m')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftButton = uibutton(app.UIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.RightButton = uibutton(app.UIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Which leg are you analyzing?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end