classdef RMP_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        EditField        matlab.ui.control.NumericEditField
        EditField_2      matlab.ui.control.NumericEditField
        Right_ACCButton  matlab.ui.control.Button
        Left_ACCButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
       Right_ACC 
       Left_ACC 
    end
       
    methods (Access = private)
        
        function LoadData(app)
            run('main_mvnx.m')
            run('Left_extraction.m')
            app.Left_ACC = (Left_ACC);
            run('Right_extraction.m')
            app.Right_ACC = (Right_ACC);
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            LoadData(app)
            
        end

        % Callback function
        function LeftExtractionButtonPushed(app, event)
           % app.left = ;
        end

        % Callback function
        function RightExtractionButtonPushed(app, event)
            %app.right = ;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            Left_ACCButtonPushed(app)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            %app.EditField_2.Value = Right_ACC;
            
        end

        % Button pushed function: Left_ACCButton
        function Left_ACCButtonPushed(app, event)
            EditFieldValueChanged(app) = app.Left_ACC;
        end

        % Button pushed function: Right_ACCButton
        function Right_ACCButtonPushed(app, event)
            app.EditField_2 = app.Right_ACC;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Left_ACCButton = uibutton(app.UIFigure, 'push');
            app.Left_ACCButton.ButtonPushedFcn = createCallbackFcn(app, @Left_ACCButtonPushed, true);
            app.Left_ACCButton.Text = 'Left_ACC';
            app.Right_ACCButton = uibutton(app.UIFigure, 'push');
            app.Right_ACCButton.ButtonPushedFcn = createCallbackFcn(app, @Right_ACCButtonPushed, true);
            app.Right_ACCButton.Text = 'Right_ACC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RMP_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end