classdef Xsens_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UIAxes            matlab.ui.control.UIAxes
        UIAxes2           matlab.ui.control.UIAxes
        Plot_LButton      matlab.ui.control.Button
        Plot_RButton      matlab.ui.control.Button
        ExportButton      matlab.ui.control.Button
        ParticipantLabel  matlab.ui.control.Label
        EditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        set; p; data; tree; ExcelFolder; XsensData;
        
    end
    
    methods (Access = private)
        
        function Load_Data(app)
            
            app.set = [1,5,6];
            
            for y = 1:length(app.set) 
                app.p = app.set(y);
                app.tree.data(y) = load_mvnx(sprintf('SCIMS_703_00%d_S1_pre_walk-1',app.p));
                               
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Load_Data(app)
        end

        % Button pushed function: Plot_LButton
        function Plot_LButtonPushed(app, event)
             for value = app.EditField.Value
                
                xlabel(app.UIAxes,'Hip [x]');
                ylabel(app.UIAxes,'Knee [y]');
                title(app.UIAxes, 'Left');
                
                
                Pelvis_l = app.tree.data(value).jointData(15).jointAngle(:,1);
                Knee_l = app.tree.data(value).jointData(20).jointAngle(:,2);
                
                plot(app.UIAxes,Pelvis_l,Knee_l);
                reset(app.UIAxes)
                
             end
        end

        % Button pushed function: Plot_RButton
        function Plot_RButtonPushed(app, event)
            for value = app.EditField.Value
                 
                 xlabel(app.UIAxes2,'Hip [x]');
                 ylabel(app.UIAxes2,'Knee [y]');
                 title(app.UIAxes2,'Right');
                 
                 Pelvis_r = app.tree.data(value).jointData(19).jointAngle(:,1);
                 Knee_r = app.tree.data(value).jointData(16).jointAngle(:,2);
                 plot(app.UIAxes2,Pelvis_r,Knee_r);
                 
                 reset(app.UIAxes2)
            end
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
        D = 'C:\Users\extlzx\Documents\MATLAB\Behavioural Data and script\behavioural data\';
        S = dir(fullfile(D,'img*'));

        SSRT = fullfile(D,S(k).name,'stopchoicert.mat');
            writetable(struct2table(S), 'extracteddata2.xlsx')
            
            
            folder = app.ExcelFolder;      %for example
        
            filename = app.XsensData;  %for example
            xlswrite(fullfile(folder, filename), 'Xsens_Data.xlsx');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Left')
            xlabel(app.UIAxes, 'Hip [x]')
            ylabel(app.UIAxes, 'Knee [y]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Right')
            xlabel(app.UIAxes2, 'Hip [x]')
            ylabel(app.UIAxes2, 'Knee [y]')
            app.Plot_LButton = uibutton(app.UIFigure, 'push');
            app.Plot_LButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_LButtonPushed, true);
            app.Plot_LButton.Text = 'Plot_L';
            app.Plot_RButton = uibutton(app.UIFigure, 'push');
            app.Plot_RButton.ButtonPushedFcn = createCallbackFcn(app, @Plot_RButtonPushed, true);
            app.Plot_RButton.Text = 'Plot_R';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ParticipantLabel = uilabel(app.UIFigure);
            app.ParticipantLabel.Text = 'Participant #';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Xsens_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end